classdef AxyDatabaseTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        fromKRSPEditField              matlab.ui.control.EditField
        fromKRSPEditFieldLabel         matlab.ui.control.Label
        FixSquirrelIDButton            matlab.ui.control.Button
        IssueRowsLabel                 matlab.ui.control.Label
        OpenIssuesLabel                matlab.ui.control.Label
        IssuesTable                    matlab.ui.control.Table
        StatusLabel                    matlab.ui.control.Label
        ConsoleTextArea                matlab.ui.control.TextArea
        ignoringfilescontainingaxylogLabel  matlab.ui.control.Label
        ImportDatabaseButton           matlab.ui.control.Button
        AxyDatabasePathEditField       matlab.ui.control.EditField
        AxyDatabasePathEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        printCount = 0;
        T_AxyDB = [];
        databaseName = "AxyDatabase.xlsx";
    end

    methods (Access = public)
        
        function aprint(app,newText,varargin)
            if strcmp(newText,'') == 0
                app.ConsoleTextArea.Value = sprintf("%03d> %s\n%s",app.printCount,newText,strjoin(app.ConsoleTextArea.Value,'\n'));
                app.printCount = app.printCount + 1;
                if nargin > 2
                    app.ConsoleTextArea.BackgroundColor = 'r';
                else
                    app.ConsoleTextArea.BackgroundColor = 'k';
                end
            else
                app.ConsoleTextArea.Value = "";
            end
        end
        
        function status(app,isProcessing)
            if isProcessing
                app.StatusLabel.Text = 'Processing';
                app.StatusLabel.BackgroundColor = [0 1 0];
                pause(.2);
            else
                app.StatusLabel.Text = 'Idle';
                app.StatusLabel.BackgroundColor = [0.5 0.5 0.5];
            end
        end
        
        function importDatabase(app)
            dbFilepath = fullfile(app.AxyDatabasePathEditField.Value,app.databaseName);
            if isfile(dbFilepath)
                aprint(app,"importing AxyDatabase.xlsx");
                % override opts
                opts = detectImportOptions(dbFilepath);
                variableNames = opts.VariableNames;
                [variableTypes{1:numel(variableNames)}] = deal('string');
                [variableTypes{contains(variableNames,{'date','time'})}] = deal('datetime');
                variableTypes(strcmp(variableNames,'squirrel_id')) = {'double'};
                opts.VariableNames = variableNames;
                opts.VariableTypes = variableTypes;
                app.T_AxyDB = readtable(dbFilepath,opts);
                populateIssues(app);
            else
                aprint(app,"no AxyDatabase.xlsx",1);
            end
        end
        
        function populateIssues(app)
            app.FixSquirrelIDButton.Enable = false;
            issueLabels = {'missing squirrel id'};
            issueTable = table;
            jj = 0;
            warning ('off','all');
            for ii = 1:size(app.T_AxyDB,1)
                theseIssues = {};
                issueCount = 0;
                if ~ismissing(app.T_AxyDB.filename(ii)) && isnan(app.T_AxyDB.squirrel_id(ii))
                    issueCount = issueCount + 1;
                    theseIssues{issueCount} = issueLabels{1}; %#ok<AGROW> 
                    app.FixSquirrelIDButton.Enable = true;
                end

                if issueCount > 0
                    jj = jj + 1;
                    issueTable.row(jj) = ii + 1; % excel row
                    issueTable.folder(jj) = app.T_AxyDB.folder(ii);
                    issueTable.filename(jj) = app.T_AxyDB.filename(ii);
                    issueTable.issues(jj) = {strjoin(theseIssues,',')};
                end
            end
            warning ('on','all');
            app.IssuesTable.Data = issueTable;
            app.IssueRowsLabel.Text = sprintf("%i Rows",jj);
        end
        
        function saveAxyDB(app)
            % backup
            copyfile(fullfile(app.AxyDatabasePathEditField.Value,app.databaseName),...
                fullfile(app.AxyDatabasePathEditField.Value,app.databaseName+".backup"));
            aprint(app,"backed up database");
            % save new
            dateFmt = "dd/MM/yyyy";
            timeFmt = "HH:mm:ss";
            % can not get this to work dynamically
            app.T_AxyDB.power_on_date = string(app.T_AxyDB.power_on_date,dateFmt);
            app.T_AxyDB.deploy_date = string(app.T_AxyDB.deploy_date,dateFmt);
            app.T_AxyDB.removed_date = string(app.T_AxyDB.removed_date,dateFmt);
            app.T_AxyDB.power_off_date = string(app.T_AxyDB.power_off_date,dateFmt);
            app.T_AxyDB.date_signature = string(app.T_AxyDB.date_signature,dateFmt);
            app.T_AxyDB.power_on_time = string(app.T_AxyDB.power_on_time,timeFmt);
            app.T_AxyDB.deploy_time = string(app.T_AxyDB.deploy_time,timeFmt);
            app.T_AxyDB.removed_time = string(app.T_AxyDB.removed_time,timeFmt);
            app.T_AxyDB.power_off_time = string(app.T_AxyDB.power_off_time,timeFmt);
            app.T_AxyDB.shake1_start_time = string(app.T_AxyDB.shake1_start_time,timeFmt);
            app.T_AxyDB.shake1_stop_time = string(app.T_AxyDB.shake1_stop_time,timeFmt);
            app.T_AxyDB.shake2_start_time = string(app.T_AxyDB.shake2_start_time,timeFmt);
            app.T_AxyDB.shake2_stop_time = string(app.T_AxyDB.shake2_stop_time,timeFmt);
            writetable(app.T_AxyDB,fullfile(app.AxyDatabasePathEditField.Value,app.databaseName));
            aprint(app,"saved database");
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            aprint(app,"Hello, Squirrel");
            status(app,false);
        end

        % Button pushed function: ImportDatabaseButton
        function ImportDatabaseButtonPushed(app, event)
            status(app,true);
            importDatabase(app);
            status(app,false);
        end

        % Button pushed function: FixSquirrelIDButton
        function FixSquirrelIDButtonPushed(app, event)
            status(app,true);
            aprint(app,'');
            krspFile = fullfile(app.AxyDatabasePathEditField.Value,app.fromKRSPEditField.Value);
            if isfile(krspFile)
                aprint(app,"reading krsp file");
                answer = questdlg(sprintf('Is %s closed?',app.databaseName),'CLOSE AXY DATABASE','Yes','No','No');
                if strcmp(answer,'Yes')
                    opts = detectImportOptions(krspFile);
                    opts.VariableTypes(contains(opts.VariableNames,'tag')) = {deal('string')};
                    T_KRSP = readtable(krspFile,opts);
                    hasIssueCount = 0;
                    fixedIssueCount = 0;
                    for ii = 1:size(app.T_AxyDB,1)
                        if ~ismissing(app.T_AxyDB.filename(ii)) && isnan(app.T_AxyDB.squirrel_id(ii))
                            hasIssueCount = hasIssueCount + 1;
                            aprint(app,".");
                            if ismissing(app.T_AxyDB.tag_right(ii)) && ismissing(app.T_AxyDB.tag_left(ii))
                                aprint(app,sprintf("no tags row %i",ii+1));
                            else
                                matchIdx = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft) & strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                                if numel(matchIdx) == 1
                                    aprint(app,sprintf("fixing row %i",ii+1));
                                    app.T_AxyDB.squirrel_id(ii) = T_KRSP.id(matchIdx);
                                    fixedIssueCount = fixedIssueCount + 1;
                                elseif numel(matchIdx) > 1
                                    for jj = 1:numel(matchIdx)
                                        aprint(app,sprintf("may be: %i-%s-%s",...
                                            T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                    end
                                    aprint(app,sprintf("--MULT IDS ROW %i--",ii+1));
                                else
                                    matchIdx = [];
                                    if strcmp(app.T_AxyDB.tag_left(ii),'-') == 0
                                        tMatch = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft));
                                        matchIdx = [matchIdx;tMatch]; %#ok<*AGROW> 
                                    end
                                    if strcmp(app.T_AxyDB.tag_right(ii),'-') == 0
                                        tMatch = find(strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                                        matchIdx = [matchIdx;tMatch];
                                    end
                                    if isempty(matchIdx)
                                        aprint(app,sprintf("no match row %i",ii+1));
                                    else
                                        for jj = 1:numel(matchIdx)
                                            aprint(app,sprintf("may be: %i-%s-%s",...
                                                T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                        end
                                        aprint(app,sprintf("--PARTIAL TAG ROW %i--",ii+1));
                                    end
                                end
                            end
                        end
                    end
                    % done fixing issues
                    aprint(app,sprintf("fixed %i/%i squirrel ids",fixedIssueCount,hasIssueCount));
                    if fixedIssueCount > 0
                        saveAxyDB(app);
                        populateIssues(app);
                    else
                        aprint(app,"no fixes, not saving");
                    end
                else
                    aprint(app,"close axy database file",true);
                end
            else
                aprint(app,"no KRSP file",true);
            end
            status(app,false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxyDatabasePathEditFieldLabel = uilabel(app.UIFigure);
            app.AxyDatabasePathEditFieldLabel.Text = 'Axy Database Path';
            app.AxyDatabasePathEditField = uieditfield(app.UIFigure, 'text');
            app.AxyDatabasePathEditField.Value = '/Volumes/GAIDICASSD/KRSP/Axy Database';
            app.ImportDatabaseButton = uibutton(app.UIFigure, 'push');
            app.ImportDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatabaseButtonPushed, true);
            app.ImportDatabaseButton.Text = 'Import Database';
            app.ignoringfilescontainingaxylogLabel = uilabel(app.UIFigure);
            app.ignoringfilescontainingaxylogLabel.Text = 'ignoring files containing  "axy" && "log"';
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.ConsoleTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.IssuesTable = uitable(app.UIFigure);
            app.IssuesTable.ColumnName = {'row'; 'folder'; 'filename'; 'issues'};
            app.IssuesTable.RowName = {};
            app.OpenIssuesLabel = uilabel(app.UIFigure);
            app.OpenIssuesLabel.Text = 'Open Issues:';
            app.IssueRowsLabel = uilabel(app.UIFigure);
            app.IssueRowsLabel.Text = '0 Rows';
            app.FixSquirrelIDButton = uibutton(app.UIFigure, 'push');
            app.FixSquirrelIDButton.ButtonPushedFcn = createCallbackFcn(app, @FixSquirrelIDButtonPushed, true);
            app.FixSquirrelIDButton.Enable = 'off';
            app.FixSquirrelIDButton.Text = 'Fix Squirrel ID';
            app.fromKRSPEditFieldLabel = uilabel(app.UIFigure);
            app.fromKRSPEditFieldLabel.Text = 'from';
            app.fromKRSPEditField = uieditfield(app.UIFigure, 'text');
            app.fromKRSPEditField.Value = '/Other/krsp_squirrel.csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxyDatabaseTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end