classdef algorithmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DataFileDropDownLabel  matlab.ui.control.Label
        DataFileDropDown       matlab.ui.control.DropDown
        TempwSliderLabel       matlab.ui.control.Label
        TempwSlider            matlab.ui.control.Slider
        DayDropDownLabel       matlab.ui.control.Label
        DayDropDown            matlab.ui.control.DropDown
        GradwSliderLabel       matlab.ui.control.Label
        GradwSlider            matlab.ui.control.Slider
        ODBAwSliderLabel       matlab.ui.control.Label
        ODBAwSlider            matlab.ui.control.Slider
        ImportButton           matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        SmGradSliderLabel      matlab.ui.control.Label
        SmGradSlider           matlab.ui.control.Slider
        SmODBASliderLabel      matlab.ui.control.Label
        SmODBASlider           matlab.ui.control.Slider
        RecalcButton           matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        SimilarityLabel        matlab.ui.control.Label
        KmeanswSliderLabel     matlab.ui.control.Label
        KmeanswSlider          matlab.ui.control.Slider
        NextDay                matlab.ui.control.Button
        PrevDay                matlab.ui.control.Button
        PathEditFieldLabel     matlab.ui.control.Label
        PathEditField          matlab.ui.control.EditField
        dataAxes               matlab.ui.control.UIAxes
        dataSuppAxes           matlab.ui.control.UIAxes
        compAxes               matlab.ui.control.UIAxes
        compSuppAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        T = table; % Description
        binNestSense = [];
        nestSense = [];
        alg_temp = [];
        alg_tempRange = [];
        alg_invOdba = [];
        alg_tempGradObda = [];
        alg_kmeansNest = [];
        temp = [];
        nest = [];
        odba = [];
        zOffset = 0;
        resetAxes = false;
        wArr = [];
        saveFilename = "config.mat";
        configPath = "";
    end
    
    methods (Access = public)
        function updateData(app)
            if isempty(app.T)
                return;
            end
            startSample = 1 + (str2double(app.DayDropDown.Value)-1)*86400;
            useSamples = 86400;
            endSample = min([startSample+useSamples-1,numel(app.nest)]);
            useRange = startSample:endSample;
            tempRange = app.temp(useRange);
            % oldNestRange = strcmp(T.Nest(useRange),'Nest');
            odbaRange = app.odba(useRange);
            
            fill_wArr(app);
            [app.binNestSense,app.nestSense,app.alg_temp,app.alg_tempRange,app.alg_invOdba,app.alg_tempGradObda,app.alg_kmeansNest] = ...
                nestSenseAlg(app.temp,app.odba,app.nest,app.wArr,app.zOffset);

            t = linspace((min(useRange)-1)/60,(max(useRange)-1)/60,numel(useRange));
            colors = lines(5);
            yyaxis(app.dataAxes,'left');
            ln1 = plot(app.dataAxes,t,odbaRange,'-');
            hold(app.dataAxes,'on');
            ln2 = plot(app.dataAxes,t,app.nestSense(useRange),'k-','linewidth',2);
            ln3 = yline(app.dataAxes,app.wArr(1),'k:','linewidth',1);
            grid(app.dataAxes,'on');
            xlabel(app.dataAxes,'Time (min)');
            ylabel(app.dataAxes,'p(Nest)');
            if app.resetAxes
                ylim(app.dataAxes,'auto');
            end
            hold(app.dataAxes,'off');
            
            yyaxis(app.dataAxes,'right');
            ln4 = plot(app.dataAxes,t,normalize(tempRange),'-','color','r','linewidth',1);
            hold(app.dataAxes,'on');
            ln5 = plot(app.dataAxes,t,app.binNestSense(useRange),'-','color',colors(5,:),'linewidth',2);
            ln6 = plot(app.dataAxes,t,app.nest(useRange)+1.1,'-','color',[colors(4,:),0.4],'linewidth',2);
            set(app.dataAxes,'ycolor','k');
            yticks(app.dataAxes,[0,1]);
            ylim(app.dataAxes,[-4 3]);
            if app.resetAxes
                xlim(app.dataAxes,[min(t),max(t)]);
                ylim(app.dataAxes,[-4 3]);
            end
            yticklabels(app.dataAxes,{'Out','In'});
            legend(app.dataAxes,[ln1,ln2,ln4,ln6,ln5],{'ODBA','p(Nest)','Temp','Orig','Sense'},...
                'AutoUpdate',"off",'fontsize',6);
            pos1 = get(app.dataAxes,'Position');
            hold(app.dataAxes,'off');
            app.SimilarityLabel.Text = sprintf("%1.1f%% alike, %1.1f%% in view",...
                100*sum(app.binNestSense==app.nest)/numel(app.nest),...
                100*sum(app.binNestSense(useRange)==app.nest(useRange))/numel(useRange));
            
            
            yyaxis(app.compAxes,'left');
            plot(app.compAxes,t,app.alg_invOdba(useRange),'-','color',colors(1,:));
            hold(app.compAxes,'on');
            plot(app.compAxes,t,app.alg_temp(useRange),'-','color',colors(2,:));
            plot(app.compAxes,t,app.alg_tempRange(useRange),'-','color',colors(3,:));
            plot(app.compAxes,t,app.alg_tempGradObda(useRange),'-','color',colors(4,:));
            plot(app.compAxes,t,app.alg_kmeansNest(useRange),'-','color',colors(5,:));
            ylabel(app.compAxes,'Z');
%             hold(app.compAxes,'off');
            if app.resetAxes
                xlim(app.compAxes,[min(t),max(t)]);
            end
            grid(app.compAxes,'on');
%             yyaxis(app.compAxes,'right');
            plot(app.compAxes,t,app.nestSense(useRange),'k-','linewidth',2);
            legend(app.compAxes,{'invOdba','temp','tempRange','tempGradOdba','kmeansNest','Sense'},...
                'AutoUpdate',"off",'fontsize',6); % InvO -> Grad
            yline(app.compAxes,0,'k:');
            ylim(app.compAxes,[-3 3]);
            pos2 = get(app.compAxes,'Position');
            set(app.compAxes,'Position',[pos2(1) pos2(2) pos1(3) pos2(4)]);
            linkaxes([app.dataAxes,app.compAxes],'x');
            hold(app.compAxes,'off');
            xlabel(app.dataAxes,'Time (min)');
            
            nMin = 20;
            windowSamples = 60*nMin;
            t = linspace(-nMin,nMin,windowSamples*2);
            for ii = 1:2
                if ii==1
                    useIds = find(diff(app.binNestSense) == 1);
                    useAx = app.dataSuppAxes;
                else
                    useIds = find(diff(app.binNestSense) == -1);
                    useAx = app.compSuppAxes;
                end
                kk = 0;
                periTemp = [];
                periOdba = [];
                for jj = 1:numel(useIds)
                    useRange = useIds(jj)-windowSamples+1:useIds(jj)+windowSamples;
                    if useRange(1) > 0 && useRange(end) < numel(app.binNestSense)
                        kk = kk + 1;
                        periTemp(kk,:) = app.temp(useRange); %#ok<*AGROW> 
                        periOdba(kk,:) = app.odba(useRange);
                    end
                end
                op = kk/400;
                yyaxis(useAx,'left');
%                 plot(useAx,t,periOdba','-','color',[colors(1,:),op]);
%                 hold(useAx,'on');
                plot(useAx,t,mean(periOdba),'-','color',colors(1,:),'linewidth',2);
                ylim(useAx,[min(mean(periOdba))-std(mean(periOdba)),max(mean(periOdba))+std(mean(periOdba))]);
                ylabel(useAx,'ODBA');
                grid(useAx,'on');
                
                yyaxis(useAx,'right');
%                 plot(useAx,t,periTemp','-','color',[1 0 0 op]);
                plot(useAx,t,mean(periTemp),'r-','linewidth',2);
                hold(useAx,'off');
                ylim(useAx,[min(mean(periTemp))-std(mean(periTemp)),max(mean(periTemp))+std(mean(periTemp))]);
                xline(useAx,0,'k:');
                yline(useAx,0,'k:');
                xlim(useAx,[min(t),max(t)]);
                xlabel(useAx,'Time (min)');
                ylabel(useAx,'Temp (C)');
                grid(useAx,'on');
                
            end
            app.resetAxes = false;
        end
        
        function fill_wArr(app)
            app.wArr = [app.KmeanswSlider.Value,app.TempwSlider.Value,app.GradwSlider.Value,...
                app.ODBAwSlider.Value,app.SmGradSlider.Value,app.SmODBASlider.Value];
        end
        
        function changeDay(app,byDays)
            dropItems = app.DayDropDown.Items;
            if ~isempty(dropItems)
                value = app.DayDropDown.Value;
                curIdx = find(strcmp(dropItems,value));
                newIdx = curIdx + byDays;
                if newIdx > 0 && newIdx <= numel(dropItems)
                    app.DayDropDown.Value = dropItems(newIdx);
                end
                app.resetAxes = true;
                updateData(app);
            end
        end
        
        function loadFileConfig(app)
            app.configPath = fullfile(app.DataFileDropDown.Value+"."+app.saveFilename);
            if isfile(app.configPath)
                load(app.configPath,'app_wArr');
                app.wArr = app_wArr; %#ok<ADPROP> 
                app.KmeanswSlider.Value = app.wArr(1);
                app.TempwSlider.Value = app.wArr(2);
                app.GradwSlider.Value = app.wArr(3);
                app.ODBAwSlider.Value = app.wArr(4);
                app.SmGradSlider.Value = app.wArr(5);
                app.SmODBASlider.Value = app.wArr(6);
                app.LoadButton.Enable = true;
            else
                app.LoadButton.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filepath = app.PathEditField.Value;
            files = dir(fullfile(filepath,"*.csv"));
            filenames = "";
            for ii = 1:numel(files)
                filenames(ii) = fullfile(filepath,files(ii).name);
            end
            app.DataFileDropDown.Items = filenames;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.Lamp.Color = [0 1 0];
            pause(0.25);
            app.T = readtable(app.DataFileDropDown.Value);
            nDays = floor(size(app.T,1) / 86400);
            app.DayDropDown.Items = compose("%i",1:nDays);
            app.Lamp.Color = [0.9,1.00,0.9];
            app.resetAxes = true;
            app.binNestSense = [];
            [app.temp,app.nest] = getTempAndNest(app.T.temp,60);
            app.odba = app.T.odba;
            app.zOffset = findZOffset(app.temp,app.odba,app.nest);
            loadFileConfig(app);
            updateData(app);
        end

        % Value changed function: DayDropDown
        function DayDropDownValueChanged(app, event)
            app.resetAxes = true;
            updateData(app);
        end

        % Button pushed function: RecalcButton
        function RecalcButtonPushed(app, event)
            updateData(app);
        end

        % Value changed function: TempwSlider
        function TempwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: GradwSlider
        function GradwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: ODBAwSlider
        function ODBAwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: SmGradSlider
        function SmGradSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: SmODBASlider
        function SmODBASliderValueChanged(app, event)
            updateData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetAxes = true;
            updateData(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fill_wArr(app);
            app_wArr = app.wArr;
            save(app.configPath,'app_wArr');
            loadFileConfig(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadFileConfig(app);
            updateData(app);
        end

        % Value changed function: KmeanswSlider
        function KmeanswSliderValueChanged(app, event)
            updateData(app);
        end

        % Button pushed function: PrevDay
        function PrevDayPushed(app, event)
            changeDay(app,-1);
        end

        % Button pushed function: NextDay
        function NextDayPushed(app, event)
            changeDay(app,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataFileDropDownLabel = uilabel(app.UIFigure);
            app.DataFileDropDownLabel.Text = 'Data File';
            app.DataFileDropDown = uidropdown(app.UIFigure);
            app.DataFileDropDown.Items = {};
            app.DataFileDropDown.Value = {};
            app.TempwSliderLabel = uilabel(app.UIFigure);
            app.TempwSliderLabel.Text = 'Temp (w)';
            app.TempwSlider = uislider(app.UIFigure);
            app.TempwSlider.Limits = [0 4];
            app.TempwSlider.MajorTicks = [0 1 2 3 4];
            app.TempwSlider.ValueChangedFcn = createCallbackFcn(app, @TempwSliderValueChanged, true);
            app.TempwSlider.MinorTicks = [];
            app.TempwSlider.Value = 1;
            app.DayDropDownLabel = uilabel(app.UIFigure);
            app.DayDropDownLabel.Text = 'Day';
            app.DayDropDown = uidropdown(app.UIFigure);
            app.DayDropDown.Items = {};
            app.DayDropDown.ValueChangedFcn = createCallbackFcn(app, @DayDropDownValueChanged, true);
            app.DayDropDown.Value = {};
            app.GradwSliderLabel = uilabel(app.UIFigure);
            app.GradwSliderLabel.Text = 'Grad (w)';
            app.GradwSlider = uislider(app.UIFigure);
            app.GradwSlider.Limits = [0 4];
            app.GradwSlider.MajorTicks = [0 1 2 3 4];
            app.GradwSlider.ValueChangedFcn = createCallbackFcn(app, @GradwSliderValueChanged, true);
            app.GradwSlider.MinorTicks = [];
            app.GradwSlider.Value = 1;
            app.ODBAwSliderLabel = uilabel(app.UIFigure);
            app.ODBAwSliderLabel.Text = 'ODBA (w)';
            app.ODBAwSlider = uislider(app.UIFigure);
            app.ODBAwSlider.Limits = [0 4];
            app.ODBAwSlider.MajorTicks = [0 1 2 3 4];
            app.ODBAwSlider.ValueChangedFcn = createCallbackFcn(app, @ODBAwSliderValueChanged, true);
            app.ODBAwSlider.MinorTicks = [];
            app.ODBAwSlider.Value = 1;
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Lamp = uilamp(app.UIFigure);
            app.SmGradSliderLabel = uilabel(app.UIFigure);
            app.SmGradSliderLabel.Text = 'Sm Grad';
            app.SmGradSlider = uislider(app.UIFigure);
            app.SmGradSlider.Limits = [1 20];
            app.SmGradSlider.MajorTicks = [1 5 10 20];
            app.SmGradSlider.ValueChangedFcn = createCallbackFcn(app, @SmGradSliderValueChanged, true);
            app.SmGradSlider.MinorTicks = [];
            app.SmGradSlider.Value = 10;
            app.SmODBASliderLabel = uilabel(app.UIFigure);
            app.SmODBASliderLabel.Text = 'Sm ODBA';
            app.SmODBASlider = uislider(app.UIFigure);
            app.SmODBASlider.Limits = [1 20];
            app.SmODBASlider.MajorTicks = [1 5 10 20];
            app.SmODBASlider.ValueChangedFcn = createCallbackFcn(app, @SmODBASliderValueChanged, true);
            app.SmODBASlider.MinorTicks = [];
            app.SmODBASlider.Value = 15;
            app.RecalcButton = uibutton(app.UIFigure, 'push');
            app.RecalcButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcButtonPushed, true);
            app.RecalcButton.Text = 'Re-calc';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SimilarityLabel = uilabel(app.UIFigure);
            app.SimilarityLabel.Text = 'Similarity';
            app.KmeanswSliderLabel = uilabel(app.UIFigure);
            app.KmeanswSliderLabel.Text = 'Kmeans (w)';
            app.KmeanswSlider = uislider(app.UIFigure);
            app.KmeanswSlider.Limits = [0 4];
            app.KmeanswSlider.MajorTicks = [0 1 2 3 4];
            app.KmeanswSlider.ValueChangedFcn = createCallbackFcn(app, @KmeanswSliderValueChanged, true);
            app.KmeanswSlider.MinorTicks = [];
            app.NextDay = uibutton(app.UIFigure, 'push');
            app.NextDay.ButtonPushedFcn = createCallbackFcn(app, @NextDayPushed, true);
            app.NextDay.Text = '>>';
            app.PrevDay = uibutton(app.UIFigure, 'push');
            app.PrevDay.ButtonPushedFcn = createCallbackFcn(app, @PrevDayPushed, true);
            app.PrevDay.Text = '<<';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Value = '/Volumes/GAIDICASSD/KRSP/KRSP Axy Data/Emily Studds Axy/September 2016';
            app.dataAxes = uiaxes(app.UIFigure);
            title(app.dataAxes, 'Main Data')
            xlabel(app.dataAxes, 'X')
            ylabel(app.dataAxes, 'Y')
            zlabel(app.dataAxes, 'Z')
            app.dataSuppAxes = uiaxes(app.UIFigure);
            title(app.dataSuppAxes, 'Peri-Enter Nest')
            xlabel(app.dataSuppAxes, 'X')
            ylabel(app.dataSuppAxes, 'Y')
            zlabel(app.dataSuppAxes, 'Z')
            app.compAxes = uiaxes(app.UIFigure);
            title(app.compAxes, 'Algorithm Components')
            xlabel(app.compAxes, 'X')
            ylabel(app.compAxes, 'Y')
            zlabel(app.compAxes, 'Z')
            app.compSuppAxes = uiaxes(app.UIFigure);
            title(app.compSuppAxes, 'Peri-Exit Nest')
            xlabel(app.compSuppAxes, 'X')
            ylabel(app.compSuppAxes, 'Y')
            zlabel(app.compSuppAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algorithmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end